classdef FDM_project < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TopBCconditionDropDown          matlab.ui.control.DropDown
        TopBCconditionDropDownLabel     matlab.ui.control.Label
        BottomBCconditionDropDown       matlab.ui.control.DropDown
        BottomBCconditionDropDownLabel  matlab.ui.control.Label
        RightBCconditionDropDown        matlab.ui.control.DropDown
        RightBCconditionDropDownLabel   matlab.ui.control.Label
        LeftBCconditionDropDown         matlab.ui.control.DropDown
        LeftBCconditionDropDownLabel    matlab.ui.control.Label
        SliderM                         matlab.ui.control.Slider
        SliderMLabel                    matlab.ui.control.Label
        SliderN                         matlab.ui.control.Slider
        SliderNLabel                    matlab.ui.control.Label
        PlotApproximateSolutionButton   matlab.ui.control.Button
        PlotExactSolutionButton         matlab.ui.control.Button
        orLabel                         matlab.ui.control.Label
        SelectEquationDropDown          matlab.ui.control.DropDown
        SelectEquationDropDownLabel     matlab.ui.control.Label
        Valueofb2EditField              matlab.ui.control.NumericEditField
        Valueofb2EditFieldLabel         matlab.ui.control.Label
        Valueofb1EditField              matlab.ui.control.NumericEditField
        Valueofb1EditFieldLabel         matlab.ui.control.Label
        ValueofqEditField               matlab.ui.control.NumericEditField
        ValueofqEditFieldLabel          matlab.ui.control.Label
        MEditField                      matlab.ui.control.NumericEditField
        MEditFieldLabel                 matlab.ui.control.Label
        TopboundaryEditField            matlab.ui.control.NumericEditField
        TopboundaryEditFieldLabel       matlab.ui.control.Label
        BottomboundaryEditField         matlab.ui.control.NumericEditField
        BottomboundaryEditFieldLabel    matlab.ui.control.Label
        ChooseYourEquationEditField     matlab.ui.control.EditField
        ChooseYourEquationEditFieldLabel  matlab.ui.control.Label
        NEditField                      matlab.ui.control.NumericEditField
        NEditFieldLabel                 matlab.ui.control.Label
        RightboundaryEditFieldLabel     matlab.ui.control.Label
        RightboundaryEditField          matlab.ui.control.NumericEditField
        LeftboundaryEditField           matlab.ui.control.NumericEditField
        LeftboundaryEditFieldLabel      matlab.ui.control.Label
        UIAxes_5                        matlab.ui.control.UIAxes
        UIAxes_4                        matlab.ui.control.UIAxes
        UIAxes_3                        matlab.ui.control.UIAxes
        UIAxes_2                        matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
    end


    properties (Access = private)   % Global variables
        a % Left boundary
        b % Right boundary
        c % Bottom boundary
        d % Top boundary
        b1 % Value of b1
        b2 % Value of b2
        q % Value of q
        f % Value of f
        stringFunction % string function
        symFunction % sym function
        NumSteps_x % N
        NumSteps_y % M
        hx % grandezza di ogni singolo quadratino sulle x
        hy % grandezza di ogni singolo quadratino sulle y
        xi % divisione dello spazio sulle x
        yj % divisione dello spazio sulle y
        Xi % matrice con gli xi
        Yj % matrice con gli yj
        u_true % True solution
        u % handle della soluzione reale
        Indx % indice sulle x
        Indy % indice sulle y
        sliderN % valore dello slider sulle N
        sliderM % valore dello slider sulle M

    end

    methods (Access = private)

        function updateplot(app,N,M)

            % Define function

            syms x y

            if isequal(app.SelectEquationDropDown.Value,'exp(2*x)*(x^2 + y^2)')
                equationStr = 'exp(2*x)*(x^2 + y^2)'; % string function
                equationSym = str2sym(equationStr); % sym function
                app.stringFunction = equationStr; % string function
                app.symFunction = equationSym; % sym function

                app.a = 0; %left boundary
                app.b = 1; %right boundary
                app.d = 1; %top boundary
                app.c = 0; %bottom boundary
                app.LeftboundaryEditField.Value = app.a; %scrivo il valore sulla barra vuota nella parte grafica
                app.RightboundaryEditField.Value = app.b; %scrivo il valore sulla barra vuota nella parte grafica
                app.BottomboundaryEditField.Value = app.c; %scrivo il valore sulla barra vuota nella parte grafica
                app.TopboundaryEditField.Value = app.d; %scrivo il valore sulla barra vuota nella parte grafica

                app.b1 = 0; %value of b1
                app.b2 = 0; %value of b2
                app.q = pi; %value of q

                app.ValueofqEditField.Value = app.q; %scrivo il valore sulla barra vuota nella parte grafica
                app.Valueofb1EditField.Value = app.b1; %scrivo il valore sulla barra vuota nella parte grafica
                app.Valueofb2EditField.Value = app.b2; %scrivo il valore sulla barra vuota nella parte grafica


            elseif isequal(app.SelectEquationDropDown.Value,'sin(2*pi*x)*sin(2*pi*y)')
                equationStr = 'sin(2*pi*x)*sin(2*pi*y)'; % string function
                equationSym = str2sym(equationStr); % sym function
                app.stringFunction = equationStr; % string function
                app.symFunction = equationSym; % sym function

                app.a = 0;
                app.b = 1;
                app.d = 1;
                app.c = 0;

                app.LeftboundaryEditField.Value = app.a;
                app.RightboundaryEditField.Value = app.b;
                app.BottomboundaryEditField.Value = app.c;
                app.TopboundaryEditField.Value = app.d;

                app.b1 = 0;
                app.b2 = 0;
                app.q = 0;

                app.ValueofqEditField.Value = app.q;
                app.Valueofb1EditField.Value = app.b1;
                app.Valueofb2EditField.Value = app.b2;

            elseif isequal(app.SelectEquationDropDown.Value,'cos(2*pi*x)*sin(2*pi*y)')
                equationStr = 'cos(2*pi*x)*sin(2*pi*y)'; % string function
                equationSym = str2sym(equationStr); % sym function
                app.stringFunction = equationStr; % string function
                app.symFunction = equationSym; % sym function

                app.a = 0;
                app.b = 1;
                app.d = 1;
                app.c = 0;

                app.LeftboundaryEditField.Value = app.a;
                app.RightboundaryEditField.Value = app.b;
                app.BottomboundaryEditField.Value = app.c;
                app.TopboundaryEditField.Value = app.d;

                app.b1 = 2;
                app.b2 = 2;
                app.q = 0;

                app.ValueofqEditField.Value = app.q;
                app.Valueofb1EditField.Value = app.b1;
                app.Valueofb2EditField.Value = app.b2;

            elseif isequal(app.SelectEquationDropDown.Value,'exp(-x-y)*(cos(pi*x)+sin(pi*y))')
                equationStr = 'exp(-x-y)*(cos(pi*x)+sin(pi*y))'; % string function
                equationSym = str2sym(equationStr); % sym function
                app.stringFunction = equationStr; % string function
                app.symFunction = equationSym; % sym function

                app.a = 0;
                app.b = 2;
                app.d = 2;
                app.c = 0;

                app.LeftboundaryEditField.Value = app.a;
                app.RightboundaryEditField.Value = app.b;
                app.BottomboundaryEditField.Value = app.c;
                app.TopboundaryEditField.Value = app.d;

                app.b1 = 0.1;
                app.b2 = 0.9;
                app.q = 1;

                app.ValueofqEditField.Value = app.q;
                app.Valueofb1EditField.Value = app.b1;
                app.Valueofb2EditField.Value = app.b2;

            else
                equationStr = app.ChooseYourEquationEditField.Value; % string function
                equationSym = str2sym(equationStr); % sym function

                app.stringFunction = equationStr; % string function
                app.symFunction = equationSym; % sym function
                app.b1 = app.Valueofb1EditField.Value; % Setto i valori che sono stati inseriti nelle apposite parti
                app.b2 = app.Valueofb2EditField.Value; % Setto i valori che sono stati inseriti nelle apposite parti
                app.q = app.ValueofqEditField.Value; % Setto i valori che sono stati inseriti nelle apposite parti
                app.a = app.LeftboundaryEditField.Value; % Setto i valori che sono stati inseriti nelle apposite parti
                app.b = app.RightboundaryEditField.Value; % Setto i valori che sono stati inseriti nelle apposite parti
                app.c = app.BottomboundaryEditField.Value; % Setto i valori che sono stati inseriti nelle apposite parti
                app.d = app.TopboundaryEditField.Value; % Setto i valori che sono stati inseriti nelle apposite parti

            end

            app.NumSteps_x = N; % valore globale di N
            app.NumSteps_y = M; % valore globale di M

            app.f=-diff(app.symFunction,x,2)-diff(app.symFunction,y,2) + ...
                app.b1.*(diff(app.symFunction,x,1)) + app.b2.*(diff(app.symFunction,y,1)) + ...
                app.q.*app.symFunction; %valore di f
            ux = diff(app.symFunction,x,1); %derivata rispetto ad x di u
            uy = diff(app.symFunction,y,1); %derivata rispetto ad y di u

            app.u = matlabFunction(app.symFunction); %u diventa un handle
            %app.f = matlabFunction(app.f);
            app.f = matlabFunction(app.f, 'vars', [x, y]);

            % Space step

            app.hx=(app.b-app.a)/(N+1);
            app.hy=(app.d-app.c)/(M+1);

            % Space subdiviosion

            app.xi=app.a:app.hx:app.b;
            app.yj=app.c:app.hy:app.d;

            app.Xi = 0;
            app.Yj = 0;

            for j=1:numel(app.yj)
                for i=1:numel(app.xi)
                    app.Xi(i,j) = app.xi(i);
                    app.Yj(i,j) = app.yj(j);
                    app.Indx(i,j)=i;
                    app.Indy(i,j)=j;
                end
            end

            % true solution

            app.u_true=app.u(app.Xi,app.Yj);

            % Plot the true solution

            surf(app.UIAxes, app.u_true);

            %  visualizzazione del grafico
            title(app.UIAxes, 'True Solution Plot');
            xlabel(app.UIAxes, 'X');
            ylabel(app.UIAxes, 'Y');
            zlabel(app.UIAxes, 'u(x, y)');

            xlim(app.UIAxes, [app.a M+2]);
            ylim(app.UIAxes, [app.c N+2]);

        end

        function [U,u_true_2,hx_2,A_cond,E_norm] = ellip2D_FD(app,N,M) %Qui dò come input N e M e non i valori globali
            % perchè poi voglio fare il grafico della convergenza e per farlo gli N e M devono cambiare ogni volta

            r = 1;

            syms x y

            ux=diff(app.symFunction,x,1);
            uy=diff(app.symFunction,y,1);

            % Questi serviranno per le boundary condition

            g_L=matlabFunction(-ux);
            g_B=matlabFunction(-uy);
            g_R=matlabFunction(ux);
            g_T=matlabFunction(uy);
            
            v_L=matlabFunction(-ux + r.*app.symFunction);
            v_B=matlabFunction(-uy + r.*app.symFunction);
            v_R=matlabFunction(ux + r.*app.symFunction);
            v_T=matlabFunction(uy + r.*app.symFunction);

            % Uso le variabili con il _2 perchè non sono più quelle globali
            % dal momento che ho cambiato N e M con gli slider

            hx_2=(app.b-app.a)/(N + 1);
            hy_2=(app.d-app.c)/(M + 1);

            % Space subdiviosion
            xi_2=app.a:hx_2:app.b;
            yj_2=app.c:hy_2:app.d;

            for j=1:numel(yj_2)
                for i=1:numel(xi_2)
                    Xi_2(i,j) = xi_2(i);
                    Yj_2(i,j) = yj_2(j);
                    Indx_2(i,j)=i;
                    Indy_2(i,j)=j;
                end
            end

            u_true_2=app.u(Xi_2,Yj_2);

            index=[Xi_2(:) Yj_2(:)];

            Th = (diag(-2*ones(N+2,1),0) + ...
                diag(ones(N+1,1),1) + diag(ones(N+1,1),-1));

            Tk = (diag(-2*ones(M+2,1),0) + ...
                diag(ones(M+1,1),1) + diag(ones(M+1,1),-1));

            Cx = (1/(2*hx_2)) * (diag(ones(N+1,1),1) - diag(ones(N+1,1),-1));

            Cy = (1/(2*hy_2)) * (diag(ones(M+1,1),1) - diag(ones(M+1,1),-1));

            % Rifaccio tutto con i valori di N e M e gli altri nuovi

            c_nodes=zeros(N+2,M+2);
            c_nodes(1:N+2,1)=1;% Bottom edge
            c_nodes(1:N+2,M+2)=1;% Top edge
            c_nodes(1,1:M+2)=1;% left
            c_nodes(N+2,1:M+2)=1;% right edge

            F = app.f(Xi_2,Yj_2);

            % LEFT BOUNDARY CONDITION

            if isequal(app.LeftBCconditionDropDown.Value,'Dirichlet')

                % Do nothing

            elseif isequal(app.LeftBCconditionDropDown.Value,'Neumann')
                
                Th(1,2)=2;
                Cx(1,2)=0;
                L_nodes=zeros(N+2,M+2);
                L_nodes(1,2:M+1)=1;% left
                gLvec=g_L(Xi_2,Yj_2).*L_nodes;
                c_nodes(1,2:M+1)=0;% left
                F=F+(2/hx_2)*gLvec + app.b1*gLvec;         

            elseif isequal(app.LeftBCconditionDropDown.Value,'Robin')

                Th(1,2)=2;
                Th(1,1) = -2*(hx_2*r + 1);
                Cx(1,2) = 0;
                Cx(1,1) = app.b1*r;
                L_nodes=zeros(N+2,M+2);
                L_nodes(1,2:M+1)=1;% left
                vLvec=v_L(Xi_2,Yj_2).*L_nodes;
                c_nodes(1,2:M+1)=0;% left
                F = F + (2/hx_2)*vLvec + app.b1*vLvec;
                
            end

            % RIGHT BOUNDARY CONDITION

            if isequal(app.RightBCconditionDropDown.Value,'Dirichlet')

                % Do nothing

            elseif isequal(app.RightBCconditionDropDown.Value,'Neumann')

                Th(end,end-1)=2;
                Cx(end,end-1)=0;
                R_nodes=zeros(N+2,M+2);
                R_nodes(N+2,2:M+1)=1;% right
                gRvec=g_R(Xi_2,Yj_2).*R_nodes;
                c_nodes(N+2,2:M+1)=0;% right
                F=F + (2/hx_2)*gRvec - app.b1*gRvec;

            elseif isequal(app.RightBCconditionDropDown.Value,'Robin')

                Th(end,end-1)=2;
                Th(end,end) = -2*(hx_2*r + 1);
                Cx(end,end-1)=0;
                Cx(end,end) = -app.b1*r;
                R_nodes=zeros(N+2,M+2);
                R_nodes(N+2,2:M+1)=1;% right
                vRvec=v_R(Xi_2,Yj_2).*R_nodes;
                c_nodes(N+2,2:M+1)=0;% right
                F=F+(2/hx_2)*vRvec - app.b1*vRvec;
               
            end

            % BOTTOM BOUNDARY CONDITION

            if isequal(app.BottomBCconditionDropDown.Value,'Dirichlet')

                % Do nothing

            elseif isequal(app.BottomBCconditionDropDown.Value,'Neumann')

                Tk(1,2)=2;
                Cy(1,2)=0;
                B_nodes=zeros(N+2,M+2);
                B_nodes(2:N+1,1)=1;% bottom
                gBvec=g_B(Xi_2,Yj_2).*B_nodes;
                c_nodes(2:N+1,1)=0;% bottom
                F=F+(2/hy_2)*gBvec + app.b2*gBvec;
      
            elseif isequal(app.BottomBCconditionDropDown.Value,'Robin')

                Tk(1,2)=2;
                Tk(1,1) = -2*(hy_2*r + 1);
                Cy(1,2)=0;
                Cy(1,1) = app.b2*r;
                B_nodes=zeros(N+2,M+2);
                B_nodes(2:N+1,1)=1;% bottom
                vBvec=v_B(Xi_2,Yj_2).*B_nodes;
                c_nodes(2:N+1,1)=0;% bottom
                F=F+(2/hy_2)*vBvec + app.b2*vBvec;

            end

            % TOP BOUNDARY CONDITION

            if isequal(app.TopBCconditionDropDown.Value,'Dirichlet')

                % Do nothing

            elseif isequal(app.TopBCconditionDropDown.Value,'Neumann')

                Tk(end,end-1)=2;
                Cy(end,end-1)=0;
                T_nodes=zeros(N+2,M+2);
                T_nodes(2:N+1,M+2)=1;% top
                gTvec=g_T(Xi_2,Yj_2).*T_nodes;
                c_nodes(2:N+1,M+2)=0;% top
                F=F + (2/hy_2)*gTvec - app.b2*gTvec;

            elseif isequal(app.TopBCconditionDropDown.Value,'Robin')

                Tk(end,end-1)=2;
                Tk(end,end) = -2*(hy_2*r + 1);
                Cy(end,end-1)=0;
                Cy(end,end)=-app.b2*r;
                T_nodes=zeros(N+2,M+2);
                T_nodes(2:N+1,M+2)=1;% top
                vTvec=v_T(Xi_2,Yj_2).*T_nodes;
                c_nodes(2:N+1,M+2)=0;% top
                F=F+(2/hy_2)*vTvec - app.b2*vTvec;
                
            end

            A_lap = -(((kron(eye(M+2,M+2),Th))./(hx_2^2))+...
                (kron(Tk,eye(N+2,N+2))./(hy_2^2)));

            A_q = app.q .* eye((N+2)*(M+2),(N+2)*(M+2));

            A_grad = app.b1 * kron(eye(M+2), Cx) + app.b2 * kron(Cy, eye(N+2));

            A = A_lap + A_q + A_grad;

            F = reshape(F,(N+2)*(M+2),1);

            Rg = u_true_2.*c_nodes;

            U = Rg;

            Rg=reshape(Rg,(N+2)*(M+2),1);

            Z=F-A*Rg;

            f_nodes=1-c_nodes;

            Ix_f=Indx_2.*f_nodes;Ix_f(Ix_f==0)=NaN;

            Iy_f=Indy_2.*f_nodes;Iy_f(Iy_f==0)=NaN;

            fnode=@(ix,jy)((N+2)*(jy-1)+ix);

            free_indx=fnode(Ix_f(:),Iy_f(:));

            free_indx=free_indx(~isnan(free_indx));

            v=A(free_indx,free_indx)\Z(free_indx);

            % position of free nodes

            Ix_f=Ix_f(~isnan(Ix_f));

            Iy_f=Iy_f(~isnan(Iy_f));

            for m=1:numel(v)

                U(Ix_f(m),Iy_f(m))=v(m);

            end

            E_norm=max(max(abs(U-u_true_2)));
            A_cond=condest(A);
            Data=[];
            Data=[Data; N, hx_2,A_cond,E_norm];

        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PlotExactSolutionButton
        function PlotExactSolutionButtonPushed(app, event)
                  
            N = app.NEditField.Value; % prendo il valore di N nello spazio vuoto
            M = app.MEditField.Value; % prendo il valore di M nello spazio vuoto

            updateplot(app,N,M);

        end

        % Button pushed function: PlotApproximateSolutionButton
        function PlotApproximateSolutionButtonPushed(app, event)

            N = app.NumSteps_x;
            M = app.NumSteps_y;

            [U,u_true_2] = ellip2D_FD(app,N,M);

            % Pulizia degli assi
            cla(app.UIAxes_4);
            cla(app.UIAxes_5);
            cla(app.UIAxes_2);
            cla(app.UIAxes_3);

            % Plot the approximate solution
            
            surf(app.UIAxes_4, U);

            
            %  visualizzazione del grafico
            title(app.UIAxes_4, 'Approximate Solution Plot');
            xlabel(app.UIAxes_4, 'X');
            ylabel(app.UIAxes_4, 'Y');
            zlabel(app.UIAxes_4, 'U');

            xlim(app.UIAxes_4, [app.a M+2]);
            ylim(app.UIAxes_4, [app.c N+2]);

            assignin('base', 'U', U);

            % Plot Error

            surf(app.UIAxes_5, abs(U-u_true_2));

            title(app.UIAxes_5, 'Error Plot');
            xlim(app.UIAxes_5, [app.a M+2]);
            ylim(app.UIAxes_5, [app.c N+2]);
            xlabel(app.UIAxes_5, 'X');
            ylabel(app.UIAxes_5, 'Y');
            zlabel(app.UIAxes_5, 'Error');


            Data = [];
            for k=2:6
                N = 2^k;
                M = 2^k;
                [U,u_true_2,hx_2,A_cond,E_norm] = ellip2D_FD(app,N,M);       
                Data=[Data; N,hx_2,A_cond,E_norm];
            end

            % Stampo la convergenza
            loglog(app.UIAxes_2,Data(:,2),Data(:,4),'red','LineWidth', 1.5) 
            hold(app.UIAxes_2,'on')
            loglog(app.UIAxes_2,Data(:,2),Data(:,2).^2,'yellow','LineWidth', 1.5);
            hold(app.UIAxes_2,'off')
            title(app.UIAxes_2, 'Convergence')
            legend(app.UIAxes_2,'Error','h^2')
            
            % Stampo il condition number
            loglog(app.UIAxes_3,Data(:,2),Data(:,3),'red','LineWidth', 1.5)
            hold(app.UIAxes_3,'on')
            loglog(app.UIAxes_3,Data(:,2),1./(Data(:,2).^2),'yellow','LineWidth', 1.5);
            title(app.UIAxes_3, 'Condition Number')
            legend(app.UIAxes_3,'Error','h^2')
            legend(app.UIAxes_3,'Cond','1/h^2')

            app.sliderN = 0;
            app.sliderM = 0;
       
        end

        % Value changed function: SliderN
        function SliderNValueChanged(app, event)

            changingValue = event.Value; % Valore dello slider
            
            N = round(changingValue);
            
            app.sliderN = N; % Assegno globalmente il valore dello slider
                 
            % Se non ho ancora usato lo slider su M utilizzo l'M nello
            % spazio vuoto, altrimento uso il valore dello slider di M

            if app.sliderM ~= 0 
                updateplot(app,app.sliderN,app.sliderM);
            else
                updateplot(app,app.sliderN,app.NumSteps_y);
            end
            
            if app.sliderM ~= 0
                [U,u_true_2] = ellip2D_FD(app,app.sliderN,app.sliderM);
            else
                [U,u_true_2] = ellip2D_FD(app,app.sliderN,app.NumSteps_y);
            end

            % Pulizia degli assi

            cla(app.UIAxes_4);
            cla(app.UIAxes_5);
            cla(app.UIAxes_2);
            cla(app.UIAxes_3);

            % Plot the approximate solution
            
            surf(app.UIAxes_4, U);
            
            %  visualizzazione del grafico
            title(app.UIAxes_4, 'Approximate Solution Plot');
            xlabel(app.UIAxes_4, 'X');
            ylabel(app.UIAxes_4, 'Y');
            zlabel(app.UIAxes_4, 'U');
            
            if app.sliderM ~= 0
                xlim(app.UIAxes_4, [app.a app.sliderM + 2]);
            else
                xlim(app.UIAxes_4, [app.a app.NumSteps_y+2]);
            end
       
            ylim(app.UIAxes_4, [app.c app.sliderN+2]);

            % Plot Error

            surf(app.UIAxes_5, abs(U-u_true_2));

            title(app.UIAxes_5, 'Error Plot');

            if app.sliderM ~= 0
                xlim(app.UIAxes_5, [app.a app.sliderM + 2]);
            else
                xlim(app.UIAxes_5, [app.a app.NumSteps_y+2]);
            end
            ylim(app.UIAxes_5, [app.c app.sliderN+2]);

            Data = [];
            for k=2:6
                N = 2^k;
                M = 2^k;
                [U,u_true_2,hx_2,A_cond,E_norm] = ellip2D_FD(app,N,M);
                Data=[Data; N,hx_2,A_cond,E_norm];
            end

            % Stampo la convergenza
            loglog(app.UIAxes_2,Data(:,2),Data(:,4),'red','LineWidth', 1.5)
            hold(app.UIAxes_2,'on')
            loglog(app.UIAxes_2,Data(:,2),Data(:,2).^2,'yellow','LineWidth', 1.5);
            hold(app.UIAxes_2,'off')
            title(app.UIAxes_2, 'Convergence')
            legend(app.UIAxes_2,'Error','h^2')

            % Stampo il condition number
            loglog(app.UIAxes_3,Data(:,2),Data(:,3),'red','LineWidth', 1.5)
            hold(app.UIAxes_3,'on')
            loglog(app.UIAxes_3,Data(:,2),1./(Data(:,2).^2),'yellow','LineWidth', 1.5);
            title(app.UIAxes_3, 'Condition Number')
            legend(app.UIAxes_3,'Error','h^2')
            legend(app.UIAxes_3,'Cond','1/h^2')

        end

        % Value changed function: SliderM
        function SliderMValueChanged(app, event)
            changingValue = event.Value;
            
            M = round(changingValue);
           
            app.sliderM = M;

            if app.sliderN ~= 0
                updateplot(app,app.sliderN,app.sliderM);
            else
                updateplot(app,app.NumSteps_x,app.sliderM);
            end

            if app.sliderN ~= 0
                [U,u_true_2] = ellip2D_FD(app,app.sliderN,app.sliderM);
            else
                [U,u_true_2] = ellip2D_FD(app,app.NumSteps_x,app.sliderM);
            end
  
            % Pulizia degli assi
            cla(app.UIAxes_4);
            cla(app.UIAxes_5);
            cla(app.UIAxes_2);
            cla(app.UIAxes_3);

            % Plot the approximate solution
            
            surf(app.UIAxes_4, U);
            
            %  visualizzazione del grafico
            title(app.UIAxes_4, 'Approximate Solution Plot');
            xlabel(app.UIAxes_4, 'X');
            ylabel(app.UIAxes_4, 'Y');
            zlabel(app.UIAxes_4, 'U');

            if app.sliderN ~= 0
                ylim(app.UIAxes_4, [app.c app.sliderN+2]);
            else
                ylim(app.UIAxes_4, [app.c app.NumSteps_x+2]);
            end

            xlim(app.UIAxes_4, [app.a app.sliderM+2]);
            
            assignin('base', 'U', U);

            % Plot Error

            surf(app.UIAxes_5, abs(U-u_true_2));

            title(app.UIAxes_5, 'Error Plot');
            
            if app.sliderN ~= 0
                ylim(app.UIAxes_5, [app.c app.sliderN+2]);
            else
                ylim(app.UIAxes_5, [app.c app.NumSteps_x+2]);
            end

            xlim(app.UIAxes_5, [app.a app.sliderM+2]);

            Data = [];
            for k=2:6
                N = 2^k;
                M = 2^k;
                [U,u_true_2,hx_2,A_cond,E_norm] = ellip2D_FD(app,N,M);
                Data=[Data; N,hx_2,A_cond,E_norm];
            end

            % Stampo la convergenza
            loglog(app.UIAxes_2,Data(:,2),Data(:,4),'red','LineWidth', 1.5)
            hold(app.UIAxes_2,'on')
            loglog(app.UIAxes_2,Data(:,2),Data(:,2).^2,'yellow','LineWidth', 1.5);
            hold(app.UIAxes_2,'off')
            title(app.UIAxes_2, 'Convergence')
            legend(app.UIAxes_2,'Error','h^2')

            % Stampo il condition number
            loglog(app.UIAxes_3,Data(:,2),Data(:,3),'red','LineWidth', 1.5)
            hold(app.UIAxes_3,'on')
            loglog(app.UIAxes_3,Data(:,2),1./(Data(:,2).^2),'yellow','LineWidth', 1.5);
            title(app.UIAxes_3, 'Condition Number')
            legend(app.UIAxes_3,'Error','h^2')
            legend(app.UIAxes_3,'Cond','1/h^2')
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Solution plot')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Convergence plot')
            xlabel(app.UIAxes_2, 'hx')
            ylabel(app.UIAxes_2, 'Error')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_3 = uiaxes(app.UIFigure);
            title(app.UIAxes_3, 'Condition number plot')
            xlabel(app.UIAxes_3, 'hx')
            ylabel(app.UIAxes_3, 'Cond matrix')
            zlabel(app.UIAxes_3, 'Z')
            app.UIAxes_4 = uiaxes(app.UIFigure);
            title(app.UIAxes_4, 'Approximate solution plot')
            zlabel(app.UIAxes_4, 'Z')
            app.UIAxes_5 = uiaxes(app.UIFigure);
            title(app.UIAxes_5, 'Error plot')
            zlabel(app.UIAxes_5, 'Z')
            app.LeftboundaryEditFieldLabel = uilabel(app.UIFigure);
            app.LeftboundaryEditFieldLabel.Text = 'Left boundary';
            app.LeftboundaryEditField = uieditfield(app.UIFigure, 'numeric');
            app.RightboundaryEditField = uieditfield(app.UIFigure, 'numeric');
            app.RightboundaryEditFieldLabel = uilabel(app.UIFigure);
            app.RightboundaryEditFieldLabel.Text = 'Right boundary';
            app.NEditFieldLabel = uilabel(app.UIFigure);
            app.NEditFieldLabel.Text = 'N';
            app.NEditField = uieditfield(app.UIFigure, 'numeric');
            app.NEditField.Value = 50;
            app.ChooseYourEquationEditFieldLabel = uilabel(app.UIFigure);
            app.ChooseYourEquationEditFieldLabel.Text = {'Choose'; 'Your'; 'Equation'};
            app.ChooseYourEquationEditField = uieditfield(app.UIFigure, 'text');
            app.BottomboundaryEditFieldLabel = uilabel(app.UIFigure);
            app.BottomboundaryEditFieldLabel.Text = 'Bottom boundary';
            app.BottomboundaryEditField = uieditfield(app.UIFigure, 'numeric');
            app.TopboundaryEditFieldLabel = uilabel(app.UIFigure);
            app.TopboundaryEditFieldLabel.Text = 'Top boundary';
            app.TopboundaryEditField = uieditfield(app.UIFigure, 'numeric');
            app.MEditFieldLabel = uilabel(app.UIFigure);
            app.MEditFieldLabel.Text = 'M';
            app.MEditField = uieditfield(app.UIFigure, 'numeric');
            app.MEditField.Value = 50;
            app.ValueofqEditFieldLabel = uilabel(app.UIFigure);
            app.ValueofqEditFieldLabel.Text = 'Value of q';
            app.ValueofqEditField = uieditfield(app.UIFigure, 'numeric');
            app.Valueofb1EditFieldLabel = uilabel(app.UIFigure);
            app.Valueofb1EditFieldLabel.Text = 'Value of b1';
            app.Valueofb1EditField = uieditfield(app.UIFigure, 'numeric');
            app.Valueofb2EditFieldLabel = uilabel(app.UIFigure);
            app.Valueofb2EditFieldLabel.Text = 'Value of b2';
            app.Valueofb2EditField = uieditfield(app.UIFigure, 'numeric');
            app.SelectEquationDropDownLabel = uilabel(app.UIFigure);
            app.SelectEquationDropDownLabel.Text = {'Select'; 'Equation'};
            app.SelectEquationDropDown = uidropdown(app.UIFigure);
            app.SelectEquationDropDown.Items = {'exp(2*x)*(x^2 + y^2)', 'cos(2*pi*x)*sin(2*pi*y)', 'exp(-x-y)*(cos(pi*x)+sin(pi*y))', 'sin(2*pi*x)*sin(2*pi*y)', '...'};
            app.SelectEquationDropDown.Value = 'exp(2*x)*(x^2 + y^2)';
            app.orLabel = uilabel(app.UIFigure);
            app.orLabel.Text = 'or';
            app.PlotExactSolutionButton = uibutton(app.UIFigure, 'push');
            app.PlotExactSolutionButton.ButtonPushedFcn = createCallbackFcn(app, @PlotExactSolutionButtonPushed, true);
            app.PlotExactSolutionButton.Text = 'Plot Exact Solution';
            app.PlotApproximateSolutionButton = uibutton(app.UIFigure, 'push');
            app.PlotApproximateSolutionButton.ButtonPushedFcn = createCallbackFcn(app, @PlotApproximateSolutionButtonPushed, true);
            app.PlotApproximateSolutionButton.Text = 'Plot Approximate Solution';
            app.SliderNLabel = uilabel(app.UIFigure);
            app.SliderNLabel.Text = 'Slider N';
            app.SliderN = uislider(app.UIFigure);
            app.SliderN.ValueChangedFcn = createCallbackFcn(app, @SliderNValueChanged, true);
            app.SliderN.Value = 50;
            app.SliderMLabel = uilabel(app.UIFigure);
            app.SliderMLabel.Text = 'Slider M';
            app.SliderM = uislider(app.UIFigure);
            app.SliderM.ValueChangedFcn = createCallbackFcn(app, @SliderMValueChanged, true);
            app.SliderM.Value = 50;
            app.LeftBCconditionDropDownLabel = uilabel(app.UIFigure);
            app.LeftBCconditionDropDownLabel.Text = 'Left BC condition';
            app.LeftBCconditionDropDown = uidropdown(app.UIFigure);
            app.LeftBCconditionDropDown.Items = {'Dirichlet', 'Neumann', 'Robin'};
            app.LeftBCconditionDropDown.Value = 'Dirichlet';
            app.RightBCconditionDropDownLabel = uilabel(app.UIFigure);
            app.RightBCconditionDropDownLabel.Text = 'Right BC condition';
            app.RightBCconditionDropDown = uidropdown(app.UIFigure);
            app.RightBCconditionDropDown.Items = {'Dirichlet', 'Neumann', 'Robin'};
            app.RightBCconditionDropDown.Value = 'Dirichlet';
            app.BottomBCconditionDropDownLabel = uilabel(app.UIFigure);
            app.BottomBCconditionDropDownLabel.Text = 'Bottom BC condition';
            app.BottomBCconditionDropDown = uidropdown(app.UIFigure);
            app.BottomBCconditionDropDown.Items = {'Dirichlet', 'Neumann', 'Robin'};
            app.BottomBCconditionDropDown.Value = 'Dirichlet';
            app.TopBCconditionDropDownLabel = uilabel(app.UIFigure);
            app.TopBCconditionDropDownLabel.Text = 'Top BC condition';
            app.TopBCconditionDropDown = uidropdown(app.UIFigure);
            app.TopBCconditionDropDown.Items = {'Dirichlet', 'Neumann', 'Robin'};
            app.TopBCconditionDropDown.Value = 'Dirichlet';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FDM_project

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end